classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GenerujdaneButton             matlab.ui.control.Button
        MetodaDropDownLabel           matlab.ui.control.Label
        MetodaDropDown                matlab.ui.control.DropDown
        InputDataTable                matlab.ui.control.Table
        ReferencePointsDataTable      matlab.ui.control.Table
        WczytajdaneButton             matlab.ui.control.Button
        RozmiarprbkiEditFieldLabel    matlab.ui.control.Label
        RozmiarProbkiEditField        matlab.ui.control.NumericEditField
        RozkadDropDown                matlab.ui.control.DropDown
        RozkadDropDownLabel           matlab.ui.control.Label
        LiczbakryteriumDropDownLabel  matlab.ui.control.Label
        LiczbakryteriumDropDown       matlab.ui.control.DropDown
        PunktyOdniesieniaLabel        matlab.ui.control.Label
        PunktOdniesieniaEditField     matlab.ui.control.NumericEditField
        DanewejcioweLabel             matlab.ui.control.Label
        PunktOdniesieniaLabel         matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        RankingButton                 matlab.ui.control.Button
        WynikLabel                    matlab.ui.control.Label
        OutputDataTable               matlab.ui.control.Table
        ZapiszDaneButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Algos = containers.Map();
        ReferencePoints;
        DistribiutionType;
        DataIn % dane wejściowe wym. liczba_rekordów x liczba_kryteriów
        RangingData % dane wyjściowe wym. jak dane wejściowe `DataIn`
        allData % Description
        nonDominated % Description
        ColumnNames;
    end
    
    methods (Access = private)
        
        function results = SaveResultsToFile(app)
            % funkcja zapisuje:
            %     dane wejściowe,
            %     wygenerowany ranging
            %     ... co jeszcze zostanie wymyslone 
            % do pliku result_<data>_<gidzina>.xlsx
            FileType = '.xlsx';
            DateTimeNow = strrep(datestr(now),' ','_');
            FileName = "results_" + strrep(DateTimeNow, ':', '-') + FileType;
            
            % Możliwa zmiana na writetable, jeżeli dane będą typu Table 
            writematrix(app.RangingData,FileName,'sheet','Ranking')
            writematrix(app.ReferencePoints,FileName,'sheet','Punkty Odniesienia')
            writematrix(app.DataIn,FileName,'sheet','Input Data')
            results =  FileName;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MetodaDropDown.Items = app.Algos.keys();
        end

        % Button pushed function: GenerujdaneButton
        function GenerujdaneButtonPushed(app, event)

            app.DataIn = [];
            distribution = app.RozkadDropDown.Value;
            no_samples = app.RozmiarProbkiEditField.Value;
            no_criterion = str2num(app.LiczbakryteriumDropDown.Value);
            app.ColumnNames = cell(no_criterion);
            dims = [1 35];
            data = [];   
            for i=1:no_criterion
                new_name = strcat('Kryterium ' ,num2str(i));
                app.ColumnNames{i} = new_name;
            end
            app.DistribiutionType = distribution;
            switch distribution
                case 'Normalny'
                    for i = 1:no_criterion
                        prompt = {'Parameter mu:', 'Parameter sigma:'};
                        definput = {'1', '0'};
                        dlgtitle = strcat('Kryterium ', num2str(i));
                        answer = inputdlg(prompt, dlgtitle, dims,definput);
                        mu = str2double(answer{1});
                        sigma = str2double(answer{2});
                        map = containers.Map({'mu', 'sigma'}, [mu, sigma]);
                        data = horzcat(data, generate_data('Normalny', no_samples, 1, map));
                    end
                case 'Eksponencjalny'
                    for i=1:no_criterion
                        prompt = {'Parameter mu:'};
                        dlgtitle = strcat('Kryterium ', num2str(i));
                        definput = {'1'};
                        answer = inputdlg(prompt, dlgtitle, dims,definput);
                        mu = str2double(answer{1});
                        map = containers.Map({'mu'}, [mu]);
                        data = horzcat(data, generate_data('Eksponencjalny', no_samples, 1, map));
                    end
                case 'Jednostajny'
                    data = horzcat(data, generate_data('Jednostajny', no_samples, no_criterion, containers.Map()));
                    
            end
            references_points = [];
            app.DataIn = data;
            app.InputDataTable.Data = app.DataIn;
            app.InputDataTable.ColumnName = app.ColumnNames;
            no_iteration = app.PunktOdniesieniaEditField.Value;
            for i=1:no_iteration
                 criterions = [];
                 definput = []
                 for j=1:no_criterion
                     criertion_name = strcat("Kryterium " , num2str(j));
                     criterions = [criterions, criertion_name];
                     definput = [definput, " 0"];
                 end
                 dlgtitle = strcat("Punkt odniesienia ", num2str(i));
                 answer = inputdlg(criterions, dlgtitle, dims,definput);
                 next_point = []
                 for k=1:length(answer)
                     next_point = [next_point, str2num(answer{k})];
                 end
                 references_points = vertcat(references_points, next_point);
            end
             app.ReferencePointsDataTable.ColumnName = app.ColumnNames;
             app.ReferencePointsDataTable.Data = references_points;
             app.ReferencePoints = references_points;
             
            msgbox('Pomyślnie wygenerowane dane');
        end

        % Callback function
        function RankingButtonPushed(app, event)
            algo_name = app.MetodaDropDown.Value;
            algo = app.Algos(algo_name);
            if ~isempty(algo.Params)
                dlgtitle = algo_name + " params";
                dims = [1 40];
                value = inputdlg(algo.Params.keys(),dlgtitle,dims,algo.Params.values());
                algo = algo.init(containers.Map(algo.Params.keys(), value));    
            end
      
            
            % tutaj zostaje zwrócona poprawnie zainicjalizowany algorytm
            % result = algo.calculate(data)
            % result stanowi wyjście z algorytmu
            
        end

        % Button pushed function: WczytajdaneButton
        function WczytajdaneButtonPushed(app, event)
            % TODO: Można tu dodać sprawdzanie flagi czy dane do wczytania z
            % pliku czy zostały wygenerowane je należy wczytać (wtedy
            % wczytywanie z pliku o okreslonej nazwie) 
            [file,path] = uigetfile('*.xlsx');
            if isequal(file,0)
               disp('User selected Cancel');
            else
                filename = fullfile(path,file);
               data_table = readtable(filename, 'Sheet','DaneWejsciowe');
               app.DataIn = data_table;
               app.InputDataTable.Data = data_table;
               app.InputDataTable.ColumnName = data_table.Properties.VariableNames;
               reference_points_table= readtable(filename, 'Sheet', 'PunktyOdniesienia');
               app.ReferencePoints = reference_points_table;
               app.ReferencePointsDataTable.Data = app.ReferencePoints;
               app.ReferencePointsDataTable.ColumnName = data_table.Properties.VariableNames;
               msgbox('Pomyślnie wczytano dane');
            end
        end

        % Callback function
        function PlotButtonPushed(app, event)
            load('testData.mat','allPoints', 'x');
            app.allData = allPoints(:, :);
            app.nonDominated = x(:, :);
            figure
            scatter3(app.allData(:, 1), app.allData(:, 2), app.allData(:, 3), 'filled');
            hold on
            scatter3(app.nonDominated(:, 1), app.nonDominated(:, 2), app.nonDominated(:, 3), 'red', 'filled');
            hold on
            scatter3(min(app.nonDominated(:, 1)), min(app.nonDominated(:, 2)), min(app.nonDominated(:, 3)), 'green', 'filled');
            grid on;
            title('Scatter plot');
            xlabel('x1');
            ylabel('x2');
            zlabel('x3');
            legend('Dominated points', 'Pareto points', 'Ideal point');
        end

        % Callback function
        function GInputButtonPushed(app, event)
            
        end

        % Button pushed function: ZapiszDaneButton
        function ZapiszDaneButtonPushed(app, event)
            save_data(app.DistribiutionType, app.DataIn, app.ReferencePoints);
            msgbox('Pomyślnie zapisano dane');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenerujdaneButton = uibutton(app.UIFigure, 'push');
            app.GenerujdaneButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujdaneButtonPushed, true);
            app.GenerujdaneButton.Text = 'Generuj dane';
            app.MetodaDropDownLabel = uilabel(app.UIFigure);
            app.MetodaDropDownLabel.Text = 'Metoda';
            app.MetodaDropDown = uidropdown(app.UIFigure);
            app.InputDataTable = uitable(app.UIFigure);
            app.InputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InputDataTable.RowName = {};
            app.ReferencePointsDataTable = uitable(app.UIFigure);
            app.ReferencePointsDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ReferencePointsDataTable.RowName = {};
            app.WczytajdaneButton = uibutton(app.UIFigure, 'push');
            app.WczytajdaneButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajdaneButtonPushed, true);
            app.WczytajdaneButton.Text = {'Wczytaj'; 'dane'};
            app.RozmiarprbkiEditFieldLabel = uilabel(app.UIFigure);
            app.RozmiarprbkiEditFieldLabel.Text = {'Rozmiar'; 'próbki'};
            app.RozmiarProbkiEditField = uieditfield(app.UIFigure, 'numeric');
            app.RozkadDropDown = uidropdown(app.UIFigure);
            app.RozkadDropDown.Items = {'Normalny', 'Eksponencjalny', 'Jednostajny'};
            app.RozkadDropDown.Value = 'Normalny';
            app.RozkadDropDownLabel = uilabel(app.UIFigure);
            app.RozkadDropDownLabel.Text = 'Rozkład';
            app.LiczbakryteriumDropDownLabel = uilabel(app.UIFigure);
            app.LiczbakryteriumDropDownLabel.Text = 'Liczba kryterium';
            app.LiczbakryteriumDropDown = uidropdown(app.UIFigure);
            app.LiczbakryteriumDropDown.Items = {'2', '3', '4', '5', '10'};
            app.LiczbakryteriumDropDown.Value = '2';
            app.PunktyOdniesieniaLabel = uilabel(app.UIFigure);
            app.PunktyOdniesieniaLabel.Text = {'Punkty'; 'Odniesienia'};
            app.PunktOdniesieniaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DanewejcioweLabel = uilabel(app.UIFigure);
            app.DanewejcioweLabel.Text = 'Dane wejściowe';
            app.PunktOdniesieniaLabel = uilabel(app.UIFigure);
            app.PunktOdniesieniaLabel.Text = 'Punkty Odniesienia';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            app.RankingButton = uibutton(app.UIFigure, 'push');
            app.RankingButton.Text = 'Ranking';
            app.WynikLabel = uilabel(app.UIFigure);
            app.WynikLabel.Text = 'Wynik';
            app.OutputDataTable = uitable(app.UIFigure);
            app.OutputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputDataTable.RowName = {};
            app.ZapiszDaneButton = uibutton(app.UIFigure, 'push');
            app.ZapiszDaneButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszDaneButtonPushed, true);
            app.ZapiszDaneButton.Text = 'Zapisz Dane';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end